classdef BeamApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        Tab                       matlab.ui.container.Tab
        Button                    matlab.ui.control.Button
        Button2                   matlab.ui.control.Button
        LabelNumericEditField3    matlab.ui.control.Label
        n1                        matlab.ui.control.NumericEditField
        LabelNumericEditField4    matlab.ui.control.Label
        n2                        matlab.ui.control.NumericEditField
        LabelNumericEditField5    matlab.ui.control.Label
        n3                        matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        n4                        matlab.ui.control.NumericEditField
        Label2                    matlab.ui.control.Label
        Label3                    matlab.ui.control.Label
        Label4                    matlab.ui.control.Label
        n5                        matlab.ui.control.NumericEditField
        Label5                    matlab.ui.control.Label
        n6                        matlab.ui.control.NumericEditField
        LabelNumericEditField6    matlab.ui.control.Label
        count                     matlab.ui.control.NumericEditField
        LabelListBox              matlab.ui.control.Label
        nodelist                  matlab.ui.control.ListBox
        LabelNumericEditField12   matlab.ui.control.Label
        n7                        matlab.ui.control.NumericEditField
        LabelNumericEditField13   matlab.ui.control.Label
        n8                        matlab.ui.control.NumericEditField
        Label11                   matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        ThenaddmembersfrommemberstabLabel  matlab.ui.control.Label
        Tab2                      matlab.ui.container.Tab
        Button3                   matlab.ui.control.Button
        Button4                   matlab.ui.control.Button
        LabelNumericEditField     matlab.ui.control.Label
        m1                        matlab.ui.control.NumericEditField
        LabelNumericEditField2    matlab.ui.control.Label
        m2                        matlab.ui.control.NumericEditField
        LabelNumericEditField7    matlab.ui.control.Label
        m3                        matlab.ui.control.NumericEditField
        LabelNumericEditField8    matlab.ui.control.Label
        count2                    matlab.ui.control.NumericEditField
        LabelListBox2             matlab.ui.control.Label
        memberlist                matlab.ui.control.ListBox
        Label12                   matlab.ui.control.Label
        Label13                   matlab.ui.control.Label
        LabelNumericEditField14   matlab.ui.control.Label
        m4                        matlab.ui.control.NumericEditField
        LabelNumericEditField15   matlab.ui.control.Label
        m5                        matlab.ui.control.NumericEditField
        LabelNumericEditField16   matlab.ui.control.Label
        m6                        matlab.ui.control.NumericEditField
        LabelNumericEditField17   matlab.ui.control.Label
        m7                        matlab.ui.control.NumericEditField
        AddmembersaccordingtotheirnodesanddeterminetheirpropertiesLabel  matlab.ui.control.Label
        ThenadddistributedloadsfromdistributedloadtabifthereisanyLabel  matlab.ui.control.Label
        Tab4                      matlab.ui.container.Tab
        LabelNumericEditField11   matlab.ui.control.Label
        nn1                       matlab.ui.control.NumericEditField
        Button9                   matlab.ui.control.Button
        LabelListBox4             matlab.ui.control.Label
        memberfb                  matlab.ui.control.ListBox
        Button10                  matlab.ui.control.Button
        LabelNumericEditField18   matlab.ui.control.Label
        nn2                       matlab.ui.control.NumericEditField
        Label14                   matlab.ui.control.Label
        Label15                   matlab.ui.control.Label
        IfthereisanydistributedloadLabel  matlab.ui.control.Label
        CreatethememberlistLabel  matlab.ui.control.Label
        SelectthememberfromthelistLabel  matlab.ui.control.Label
        AddloadbypressingaddchangebuttonLabel  matlab.ui.control.Label
        IfselfweightisincludedfromselfweighttabitcanbedeterminedLabel  matlab.ui.control.Label
        Tab5                      matlab.ui.container.Tab
        LabelNumericEditField19   matlab.ui.control.Label
        sww                       matlab.ui.control.NumericEditField
        Label16                   matlab.ui.control.Label
        Button12                  matlab.ui.control.Button
        DeterminetheunitweightandapplyitLabel  matlab.ui.control.Label
        axe                       matlab.ui.control.UIAxes
        Button5                   matlab.ui.control.Button
        Label6                    matlab.ui.control.Label
        Label7                    matlab.ui.control.Label
        Label8                    matlab.ui.control.Label
        Label9                    matlab.ui.control.Label
        Label10                   matlab.ui.control.Label
        Button11                  matlab.ui.control.Button
    end

    
    methods (Access = public)
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            format short;
            app.nodelist.Items = {};
            app.memberlist.Items = {};
            app.memberfb.Items = {};
            global nodedata;
            global hence;
            global swa;
            swa =0;
            hence=0;
            nodedata=zeros(1,8);
            global node;
            node = 0;
            global memberdata;
            memberdata=zeros(1,21);
            global member;
            member =0;
            hold(app.axe,'on');
            global ifdof;
            ifdof=1;
            global loaded;
            loaded =0;
            clear;
            clc;
            
        end

        % Button pushed function: Button
        function ButtonButtonPushed(app, event)
            
            
            global plotdata;
            global node;
            node = node+1;
            global nodedata;
            global textdata;
            global forceddata;
            global forcetdata;
            global supportddata;
            nodedata(node,:)=[app.n1.Value,app.n2.Value,app.n3.Value,app.n4.Value,app.n7.Value,app.n5.Value,app.n6.Value,app.n8.Value];
            app.nodelist.Items{node} = int2str(node);
            app.count.Value = node;
            plotdata(node,1)=plot(app.axe,app.n1.Value,app.n2.Value,'.-','Color','blue');
            textdata(node,1)=text(app.axe,app.n1.Value-0.6,app.n2.Value-0.6,int2str(node),'Color','blue','FontSize',16);
            
            if app.n5.Value ~= 0
                forceddata(node,1)=plot(app.axe,[app.n1.Value-0.1,app.n1.Value-0.9], [app.n2.Value,app.n2.Value],'Color','red');
                forcetdata(node,1)=text(app.axe,app.n1.Value-1.2,app.n2.Value,{int2str(app.n5.Value),' kN'},'Color','red','FontSize',16);
                forceddata(node,3)=plot(app.axe,[app.n1.Value-0.1,app.n1.Value-0.2], [app.n2.Value,app.n2.Value-0.2],'Color','red');
                forceddata(node,4)=plot(app.axe,[app.n1.Value-0.1,app.n1.Value-0.2], [app.n2.Value,app.n2.Value+0.2],'Color','red');
            end
            if app.n6.Value ~= 0
                forceddata(node,2)=plot(app.axe,[app.n1.Value,app.n1.Value], [app.n2.Value-0.1,app.n2.Value-0.9],'Color','red');
                forcetdata(node,2)=text(app.axe,app.n1.Value,app.n2.Value-1.2,{int2str(app.n6.Value),' kN'},'Color','red','FontSize',16);
                forceddata(node,5)=plot(app.axe,[app.n1.Value,app.n1.Value-0.1], [app.n2.Value-0.1,app.n2.Value-0.2],'Color','red');
                forceddata(node,6)=plot(app.axe,[app.n1.Value,app.n1.Value+0.1], [app.n2.Value-0.1,app.n2.Value-0.2],'Color','red');
            end
            if app.n3.Value ==1 && app.n4.Value ==1 && app.n7.Value ==1
                supportddata(node,1)=plot(app.axe,[app.n1.Value-0.4,app.n1.Value+0.4], [app.n2.Value,app.n2.Value],'Color','green');
                supportddata(node,2)=plot(app.axe,[app.n1.Value-0.4,app.n1.Value-0.4], [app.n2.Value,app.n2.Value-0.4],'Color','green');
                supportddata(node,3)=plot(app.axe,[app.n1.Value+0.4,app.n1.Value+0.4], [app.n2.Value,app.n2.Value-0.4],'Color','green');
                supportddata(node,4)=plot(app.axe,[app.n1.Value-0.4,app.n1.Value+0.4], [app.n2.Value-0.4,app.n2.Value-0.4],'Color','green');
            elseif app.n3.Value ==1 && app.n4.Value ==1
                supportddata(node,1)=plot(app.axe,[app.n1.Value,app.n1.Value-0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(node,2)=plot(app.axe,[app.n1.Value,app.n1.Value+0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(node,3)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value+0.3], [app.n2.Value-0.5,app.n2.Value-0.5],'Color','green');
                supportddata(node,4)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value+0.3], [app.n2.Value-0.5,app.n2.Value-0.5],'Color','green');
            elseif app.n4.Value ==1
                supportddata(node,1)=plot(app.axe,[app.n1.Value,app.n1.Value-0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(node,2)=plot(app.axe,[app.n1.Value,app.n1.Value+0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(node,3)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value+0.3], [app.n2.Value-0.5,app.n2.Value-0.5],'Color','green');
                supportddata(node,4)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value+0.3], [app.n2.Value-0.7,app.n2.Value-0.7],'Color','green');
            elseif app.n3.Value ==1
                supportddata(node,1)=plot(app.axe,[app.n1.Value,app.n1.Value-0.3], [app.n2.Value,app.n2.Value+0.5],'Color','green');
                supportddata(node,2)=plot(app.axe,[app.n1.Value,app.n1.Value-0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(node,3)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value-0.3], [app.n2.Value+0.5,app.n2.Value-0.5],'Color','green');
                supportddata(node,4)=plot(app.axe,[app.n1.Value-0.5,app.n1.Value-0.5], [app.n2.Value+0.5,app.n2.Value-0.5],'Color','green');
            end
            
            gx=0;
            for i=1:node
                if gx < nodedata(i,1)
                    gx=nodedata(i,1);
                end
            end
            
            gy=0;
            for i=1:node
                if gy < nodedata(i,2)
                    gy=nodedata(i,2);
                end
                
            end
            
            app.axe.XLim = [-3,gx+3];
            app.axe.YLim = [-3,gy+3];
            
        end

        % Button pushed function: Button3
        function Button3ButtonPushed(app, event)
            
            global nodedata;
            global plotdata;
            global member;
            member = member+1;
            global memberdata;
            
            global textdata;
            k1=app.m1.Value;
            k2=app.m2.Value;
            x1=nodedata(k1,1);
            y1=nodedata(k1,2);
            x2=nodedata(k2,1);
            y2=nodedata(k2,2);
            x=x2-x1;
            y=y2-y1;
            l=sqrt(x*x+y*y);
            c=x/l;
            s=y/l;
            me=app.m3.Value;
            ma=app.m4.Value*app.m5.Value;
            app.m6.Value=ma;
            mi=app.m4.Value*app.m4.Value*app.m4.Value*app.m5.Value/12;
            app.m7.Value=mi;
            ea=me*ma;
            ei=me*mi;
            memberdata(member,:)=[x1,y1,x2,y2,me,ma,ea,ei,k1,k2,l,c,s,0,0,0,0,0,0,0,0];
            app.memberlist.Items{member} = int2str(member);
            plotdata(member,2)=plot(app.axe,[memberdata(member,1),memberdata(member,3)],[memberdata(member,2),memberdata(member,4)],'Color','black');
            xort=(x2+x1)/2;
            yort=(y2+y1)/2;
            textdata(member,2)=text(app.axe,xort-0.3,yort-0.3,int2str(member),'Color','black','FontSize',16);
            app.count2.Value = member;


        end

        % Button pushed function: Button5
        function Button5ButtonPushed(app, event)
            global dofdata;
            global memberdata;
            global member;
            global nodedata;
            global node;
            global Kgl;
            global KLocal;
            global Krotation;
            global forcedata;
            global forcefddata;
            global Fsupport;
            global sk;
            global sw;
            global swa;
            global Mgforces;
            global Mlforces;
            global Miforces;
            global forse;
            global loaded;
            
            for i=1:member
                x1=nodedata(memberdata(i,9),1);
                y1=nodedata(memberdata(i,9),2);
                x2=nodedata(memberdata(i,10),1);
                y2=nodedata(memberdata(i,10),2);
                x=x2-x1;
                y=y2-y1;
                l=sqrt(x*x+y*y);
                c=x/l;
                s=y/l;
                memberdata(i,1)=x1;
                memberdata(i,2)=y1;
                memberdata(i,3)=x2;
                memberdata(i,4)=y2;
                memberdata(i,11)=l;
                memberdata(i,12)=c;
                memberdata(i,13)=s;
            end
            
            dofdata=zeros(node,3);
            sk = 0;
            for i=1:node
                for j=3:5
                    if nodedata(i,j) == 0
                        sk = sk+1;
                        dofdata(i,j-2)=sk;
                    end
                end
            end
            
            for i=1:member
                memberdata(i,14)=dofdata(memberdata(i,9),1);
                memberdata(i,15)=dofdata(memberdata(i,9),2);
                memberdata(i,16)=dofdata(memberdata(i,9),3);
                memberdata(i,17)=dofdata(memberdata(i,10),1);
                memberdata(i,18)=dofdata(memberdata(i,10),2);
                memberdata(i,19)=dofdata(memberdata(i,10),3);
            end
            
            Kgl=zeros(sk,sk);
            KLocal= zeros(6,6,member);
            for i=1:member
                KLocal(1,1,i)=memberdata(i,7)/memberdata(i,11);
                KLocal(1,4,i)=-memberdata(i,7)/memberdata(i,11);
                KLocal(2,2,i)=12*memberdata(i,8)/memberdata(i,11)^3;
                KLocal(2,3,i)=6*memberdata(i,8)/memberdata(i,11)^2;
                KLocal(2,5,i)=-12*memberdata(i,8)/memberdata(i,11)^3;
                KLocal(2,6,i)=6*memberdata(i,8)/memberdata(i,11)^2;
                KLocal(3,2,i)=6*memberdata(i,8)/memberdata(i,11)^2;
                KLocal(3,3,i)=4*memberdata(i,8)/memberdata(i,11);
                KLocal(3,5,i)=-6*memberdata(i,8)/memberdata(i,11)^2;
                KLocal(3,6,i)=2*memberdata(i,8)/memberdata(i,11);
                KLocal(4,1,i)=-memberdata(i,7)/memberdata(i,11);
                KLocal(4,4,i)=memberdata(i,7)/memberdata(i,11);
                KLocal(5,2,i)=-12*memberdata(i,8)/memberdata(i,11)^3;
                KLocal(5,3,i)=-6*memberdata(i,8)/memberdata(i,11)^2;
                KLocal(5,5,i)=12*memberdata(i,8)/memberdata(i,11)^3;
                KLocal(5,6,i)=-6*memberdata(i,8)/memberdata(i,11)^2;
                KLocal(6,2,i)=6*memberdata(i,8)/memberdata(i,11)^2;
                KLocal(6,3,i)=2*memberdata(i,8)/memberdata(i,11);
                KLocal(6,5,i)=-6*memberdata(i,8)/memberdata(i,11)^2;
                KLocal(6,6,i)=4*memberdata(i,8)/memberdata(i,11);
            end
            Krotation=zeros(6,6,member);
            for i=1:member
                Krotation(1,1,i)=memberdata(i,12);
                Krotation(1,2,i)=memberdata(i,13);
                Krotation(2,1,i)=-memberdata(i,13);
                Krotation(2,2,i)=memberdata(i,12);
                Krotation(3,3,i)=1;
                Krotation(4,4,i)=memberdata(i,12);
                Krotation(4,5,i)=memberdata(i,13);
                Krotation(5,4,i)=-memberdata(i,13);
                Krotation(5,5,i)=memberdata(i,12);
                Krotation(6,6,i)=1;
            end
            
            Kglobal=zeros(6,6,member);
            for i=1:member
                Kglobal(: ,:,i)=Krotation(:,:,i)'*KLocal(:,:,i)*Krotation(:,:,i);
            end
            for i=1:member
                for x=1:6
                    for y=1:6
                        if memberdata(i,x+13) ~= 0 && memberdata(i,y+13) ~= 0
                            Kgl(memberdata(i,x+13),memberdata(i,y+13))=Kglobal(x,y,i)+Kgl(memberdata(i,x+13),memberdata(i,y+13));
                        end
                    end
                end
            end
            
            if swa==1
                for i=1:member
                    if memberdata(i,12) ==1
                        memberdata(i,20)=-sw*memberdata(i,6);
                        memberdata(i,21)=-sw*memberdata(i,6);
                    elseif memberdata(i,13)==1
                        nodedata(memberdata(i,9),7)=-sw*memberdata(i,6)*memberdata(i,11)+nodedata(memberdata(i,9),7);
                    end
                end
            end
            
            
            forcedata = zeros(sk,1);
            for i=1:node
                for j=1:3
                    if dofdata(i,j) ~= 0
                       forcedata(dofdata(i,j),1)=forcedata(dofdata(i,j),1)+nodedata(i,j+5);
                    end
                end
            end
            

            femldata=zeros(6,1,member);
            femgdata=zeros(6,1,member);
            for i=1:member
                val1=abs(memberdata(i,21)-memberdata(i,20));
                momu=abs(memberdata(i,20))*memberdata(i,11)^2/12;
                momu2=val1*memberdata(i,11)^2/20;
                momu1=val1*memberdata(i,11)^2/30;
                forsu = abs(memberdata(i,20))*memberdata(i,11)/2;
                forsu2 = val1*memberdata(i,11)/3+momu2/memberdata(i,11)-momu1/memberdata(i,11);
                forsu1 = val1*memberdata(i,11)/2/3+momu1/memberdata(i,11)-momu2/memberdata(i,11);

                    femldata(2,1,i)=(forsu+forsu1);
                    femldata(3,1,i)=(momu+momu1);
                    femldata(5,1,i)=(forsu+forsu2);
                    femldata(6,1,i)=-(momu+momu2);
                    femgdata(:,:,i)=Krotation(:,:,i)^-1*femldata(:,:,i);
                
            end
            
            forcefddata=zeros(sk,1);
            for i=1:member
                for j=14:19
                    if memberdata(i,j) ~= 0
                        forcefddata(memberdata(i,j),1)=femgdata(j-13,1,i)+forcefddata(memberdata(i,j),1);
                    end
                end
            end
                
            forcedata=forcedata-forcefddata;
            
            u=linsolve(Kgl,forcedata);
            
            Mlforces=zeros(6,1,member);
            
            for i=1:member
                uk=zeros(6,1);
                
                    for j=1:6
                        if memberdata(i,j+13) ~= 0
                            uk(j,1)=u(memberdata(i,j+13),1);
                        elseif memberdata(i,j+13) == 0
                            uk(j,1)=0;
                        end
                    end

                    uk=Krotation(:,:,i)*uk;
                    Mlforces(:,1,i)=KLocal(:,:,i)*uk;
                
            end
            
            for i=1:member
                    Mlforces(:,:,i)=femldata(:,:,i)+Mlforces(:,:,i);
            end
            
            Mgforces=zeros(6,1,member);
            for i=1:member
                Mgforces(:,:,i)=Krotation(:,:,i)^-1*Mlforces(:,:,i);
            end
            
            Miforces=zeros(6,1,member);
            for i=1:member
                for j=1:6
                    if j <4
                        Miforces(j,1,i)=Mlforces(j,1,i)*-1;
                    else
                        Miforces(j,1,i)=Mlforces(j,1,i);
                    end
                end
            end

            Fsupport=zeros(node*3-sk,1);
            cnt=1;
            for i=1:member
                for j=14:19
                if memberdata(i,j) == 0
                        Fsupport(cnt,1)=Mgforces(j-13,1,i)+Fsupport(cnt,1);
                        cnt=cnt+1;
                end
                end
            end
            
            cnt=1;
            for i=1:node
                for j=3:5
                    if nodedata(i,j) == 1
                        Fsupport(cnt,1)=Fsupport(cnt,1)-nodedata(i,j+3);
                        cnt=cnt+1;
                    end
                end
            end


            %code below this line is just for arrangement of the tables and graphical issues
            KS=zeros(floor((node*3-sk)/3),1);
            for i=1:node
                if nodedata(i,3) == 1 || nodedata(i,4)== 1 || nodedata(i,5)== 1
                    KS(i,1)=i;
                end
            end

            KSF=zeros(floor((node*3-sk)/3),3);
            sd=1;
            countt=1;
            for i=1:node*3-sk
                if sd==1
                    KSF(countt,sd)=Fsupport(3*countt-2,1);
                    sd=2;
                elseif sd==2
                    KSF(countt,sd)=Fsupport(3*countt-1,1);
                    sd=3;
                elseif sd==3
                    KSF(countt,sd)=Fsupport(3*countt,1);
                    sd=1;
                    countt=countt+1;
                end
            end
            
            suport = table(KS(:,1),KSF(:,1),KSF(:,2),KSF(:,3));
            suport.Properties.VariableNames = {'Node_No','X_dir_Force_kN','Y_dir_Force_kN','Moment_kNm'};

            DS=zeros(sk,2);
            
            cnt=1;
            for i=1:node
                for j=1:3
                    if dofdata(i,j) ~= 0
                        DS(cnt,1)=i;
                        DS(cnt,2)=dofdata(i,j);
                        cnt=cnt+1;
                    end
                end
            end
            
            def = table(DS(:,1),DS(:,2),u(:,1));
            def.Properties.VariableNames = {'Node_No','Dof_No','Displacement'};
            
            forse = table(DS(:,1),DS(:,2),forcedata(:,1));
            forse.Properties.VariableNames = {'Node_No','Dof_No','Forces_and_Moments'};
            
            
            %outputs
            assignin('base','femlocaldata',femldata);
            assignin('base','femglobaldata',femgdata);
            assignin('base','forcematrix',forcedata);
            assignin('base','memberdata',memberdata);
            assignin('base','nodedata',nodedata);
            assignin('base','dofdata',dofdata);
            assignin('base','Fixed_End_Moments',forcefddata);
            assignin('base','Mlforces',Mlforces);
            assignin('base','Mgforces',Mgforces);
            assignin('base','Miforces',Miforces);
            assignin('base','Klocal_Member',KLocal);
            assignin('base','KGlobal_Structure',Kgl);
            assignin('base','Force_Matrix',forse);
            fprintf('\nSupport Reactions\n');
            disp(suport);
            assignin('base','Support_Reactions',suport);
            fprintf('\nDeflections\n');
            disp(def);
            assignin('base','Deflections',def);
            
            
            hold on;
            title('Discretization');
            
            %calculates plot area
            gx=0;
            for i=1:member
                if gx < memberdata(i,3)
                    gx=memberdata(i,3);
                end
                if gx < memberdata(i,1)
                    gx=memberdata(i,1);
                end
            end
            
            gy=0;
            for i=1:member
                if gy < memberdata(i,4)
                    gy=memberdata(i,4);
                end
                if gy < memberdata(i,2)
                    gy=memberdata(i,2);
                end
            end
            
            xlim([-2,gx+2]);
            ylim([-2,gy+2]);
            
            %plots members
            for i=1:member
                line([memberdata(i,1),memberdata(i,3)],[memberdata(i,2),memberdata(i,4)],'Color','black');
                xort=(memberdata(i,1)+memberdata(i,3))/2;
                yort=(memberdata(i,4)+memberdata(i,2))/2;
                text(xort+0.3,yort-0.3,int2str(i),'Color','black','FontSize',8);
            end
            
            %plots dofs
            for i=1:node
                for j=1:3
                    if dofdata(i,j) ~= 0
                        if j==1
                            quiver(nodedata(i,1)-0.5,nodedata(i,2)+0.5,0.5,0,'MaxHeadSize',1.5,'Color','r','LineWidth',2);
                            text(nodedata(i,1)+0.05,nodedata(i,2)+0.5,int2str(dofdata(i,j)),'Color','r');
                        elseif j==2
                            quiver(nodedata(i,1)-0.5,nodedata(i,2)+0.5,0,0.5,'MaxHeadSize',1.5,'Color','r','LineWidth',2);
                            text(nodedata(i,1)-0.5,nodedata(i,2)+1.05,int2str(dofdata(i,j)),'Color','r');
                        else
                            quiver(nodedata(i,1)-0.5,nodedata(i,2)+0.5,0.5,0.5,'MaxHeadSize',1.5,'Color','r','LineWidth',2);
                            quiver(nodedata(i,1)-0.5,nodedata(i,2)+0.5,0.3,0.3,'MaxHeadSize',1.5,'Color','r','LineWidth',2);
                            text(nodedata(i,1)-0.05,nodedata(i,2)+1.05,int2str(dofdata(i,j)),'Color','r');
                        end
                    end
                end
            end
            
            %plots nodes
            for i=1:node
            plot(nodedata(i,1),nodedata(i,2),'.-','Color','blue');
            text(nodedata(i,1)-0.2,nodedata(i,2)-0.2,int2str(i),'Color','blue','FontSize',8);
            end
            
            text(-1,-1,'Blue>Nodes , Red>DOFs , Black>Members');
            if loaded ==1
            f1 = figure('Name','Moment Diagram Member 9,10,11 A-B-C-D');
            hold on;
            xlim([-2,gx+2]);
            ylim([3,8]);
            
            for i=9:11
                line([memberdata(i,1),memberdata(i,3)],[memberdata(i,2),memberdata(i,4)],'Color','black');
                xort=(memberdata(i,1)+memberdata(i,3))/2;
                yort=(memberdata(i,4)+memberdata(i,2))/2;
                text(xort+0.1,yort-0.1,int2str(i),'Color','black','FontSize',8);
            end
            
            for i=7:10
            plot(nodedata(i,1),nodedata(i,2),'.-','Color','blue');
            text(nodedata(i,1)-0.1,nodedata(i,2)-0.1,int2str(i),'Color','blue','FontSize',8);
            end
            
            for i=9:11
                    line([nodedata(i-2,1),nodedata(i-2,1)],[nodedata(i-2,2),nodedata(i-2,2)+Miforces(3,1,i)/50],'Color','red');
                    text(nodedata(i-2,1)+0.4,nodedata(i-2,2)+Miforces(3,1,i)/50+0.1,num2str(Miforces(3,1,i)),'Color','red','FontSize',8);
                    line([nodedata(i-1,1),nodedata(i-1,1)],[nodedata(i-1,2),nodedata(i-1,2)+Miforces(6,1,i)/50],'Color','red');
                    text(nodedata(i-1,1)-0.7,nodedata(i-1,2)+Miforces(6,1,i)/50-0.1,num2str(Miforces(6,1,i)),'Color','red','FontSize',8);
            end
            
            xx1=[0 0.4 0.8 1.2 1.6 2 2.4 2.8 3.2 3.6 4];
            yy1=[6+35.05/50 6+31.43/50 6+26.87/50 6+21.37/50 6+14.92/50 6+7.53/50 6-0.81/50 6-10.09/50 6-20.31/50 6-31.48/50 6-43.60/50];
            plot(xx1,yy1,'Color','red');
            xx2=[4 4.4 4.8 5.2 5.6 6 6.4 6.8 7.2 7.6 8];
            yy2=[6+35.07/50 6+29.4/50 6+22.79/50 6+15.23/50 6+6.72/50 6-2.73/50 6-13.12/50 6-24.46/50 6-36.74/50 6-49.96/50 6-64.13/50];
            plot(xx2,yy2,'Color','red');
            xx3=[8 8.3 8.6 8.9 9.2 9.5 9.8 10.1 10.4 10.7 11];
            yy3=[6-56.55/50 6-48.51/50 6-40.99/50 6-34.01/50 6-27.56/50 6-21.64/50 6-16.25/50 6-11.39/50 6-7.06/50 6-3.37/50 6-0/50];
            plot(xx3,yy3,'Color','red');
            
            axis off;
            
            text(0,4,'NOTE: Moment Diagram is actully quadratic but in terms of simplicity it is drawn with lines via hand calculation.');
            text(0,8,'Units; kNm');
            end
            f = warndlg('Solutions are in the MATLAB COMMAND WINDOW','SOLVED');
            
        end

        % Value changed function: nodelist
        function nodelistValueChanged(app, event)
            global nodedata;
            global temp;
            global varx;
            global vary;
            global supx;
            global supy;
            value = str2double(app.nodelist.Value);
            temp=value;
            app.n1.Value = nodedata(value,1);
            app.n2.Value = nodedata(value,2);
            app.n3.Value = nodedata(value,4);
            app.n4.Value = nodedata(value,5);
            app.n5.Value = nodedata(value,6);
            app.n6.Value = nodedata(value,7);
            if app.n5.Value ~= 0
                varx =1;
            end
            if app.n6.Value ~= 0
                vary =1;
            end
            if app.n3.Value ~= 0
                supx =1;
            end
            if app.n4.Value ~= 0
                supy =1;
            end
            app.Button2.Enable = 'on';
        end

        % Button pushed function: Button4
        function Button4ButtonPushed(app, event)
            global plotdata;
            global member;
            global memberdata;
            global nodedata;
            global temp;
            global textdata;
            k1=app.m1.Value;
            k2=app.m2.Value;
            x1=nodedata(k1,1);
            y1=nodedata(k1,2);
            x2=nodedata(k2,1);
            y2=nodedata(k2,2);
            x=x2-x1;
            y=y2-y1;
            l=sqrt(x*x+y*y);
            c=x/l;
            s=y/l;
            cs=c*s;
            c2=c*c;
            s2=s*s;
            
            memberdata(temp,:)=[x1,y1,x2,y2,c2,s2,cs,app.m3.Value,k1,k2,0,0,0,0,l,c,s];
            delete(plotdata(temp,2));
            delete(textdata(temp,2));
            plotdata(temp,2)=plot(app.axe,[memberdata(member,1),memberdata(member,3)],[memberdata(member,2),memberdata(member,4)],'Color','black');
            xort=(x2+x1)/2;
            yort=(y2+y1)/2;
            
            textdata(member,2)=text(app.axe,xort-0.3,yort-0.3,int2str(temp),'Color','black','FontSize',16);
            
            app.Button4.Enable = 'off';


        end

        % Button pushed function: Button2
        function Button2ButtonPushed(app, event)
            global plotdata;
            global nodedata;
            global temp;
            global textdata;
            global forceddata;
            global forcetdata;
            global varx;
            global vary;
            global supportddata;
            global supx;
            global supy;
            
            nodedata(temp,:)=[app.n1.Value,app.n2.Value,0,app.n3.Value,app.n4.Value,app.n5.Value,app.n6.Value,0,0];
            if app.n3.Value == 1
                nodedata(temp,3)=1;
            elseif app.n4.Value == 1
                nodedata(temp,3)=1;
            end
            delete(plotdata(temp,1));
            delete(textdata(temp,1));
            if varx ==1
                delete(forceddata(temp,1));
                delete(forcetdata(temp,1));
                delete(forceddata(temp,3));
                delete(forceddata(temp,4));
            end
            if vary ==1
                delete(forceddata(temp,5));
                delete(forceddata(temp,6));
                delete(forcetdata(temp,2));
                delete(forceddata(temp,2));
            end
            if supx==1||supy==1
                delete(supportddata(temp,1));
                delete(supportddata(temp,2));
                delete(supportddata(temp,3));
                delete(supportddata(temp,4));
            end
            plotdata(temp,1)=plot(app.axe,app.n1.Value,app.n2.Value,'.-','Color','blue');
            textdata(temp,1)=text(app.axe,app.n1.Value-0.6,app.n2.Value-0.6,int2str(temp),'Color','blue','FontSize',16);
            
            
            if app.n5.Value ~= 0
                forceddata(temp,1)=plot(app.axe,[app.n1.Value-0.1,app.n1.Value-0.9], [app.n2.Value,app.n2.Value],'Color','red');
                forcetdata(temp,1)=text(app.axe,app.n1.Value-1.2,app.n2.Value,{int2str(app.n5.Value),' kN'},'Color','red','FontSize',16);
                forceddata(temp,3)=plot(app.axe,[app.n1.Value-0.1,app.n1.Value-0.2], [app.n2.Value,app.n2.Value-0.2],'Color','red');
                forceddata(temp,4)=plot(app.axe,[app.n1.Value-0.1,app.n1.Value-0.2], [app.n2.Value,app.n2.Value+0.2],'Color','red');
            end
            if app.n6.Value ~= 0
                forceddata(temp,2)=plot(app.axe,[app.n1.Value,app.n1.Value], [app.n2.Value-0.1,app.n2.Value-0.9],'Color','red');
                forcetdata(temp,2)=text(app.axe,app.n1.Value,app.n2.Value-1.2,{int2str(app.n6.Value),' kN'},'Color','red','FontSize',16);
                forceddata(temp,5)=plot(app.axe,[app.n1.Value,app.n1.Value-0.1], [app.n2.Value-0.1,app.n2.Value-0.2],'Color','red');
                forceddata(temp,6)=plot(app.axe,[app.n1.Value,app.n1.Value+0.1], [app.n2.Value-0.1,app.n2.Value-0.2],'Color','red');
            end
            
            if app.n3.Value ==1 && app.n4.Value ==1
                supportddata(temp,1)=plot(app.axe,[app.n1.Value,app.n1.Value-0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(temp,2)=plot(app.axe,[app.n1.Value,app.n1.Value+0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(temp,3)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value+0.3], [app.n2.Value-0.5,app.n2.Value-0.5],'Color','green');
                supportddata(temp,4)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value+0.3], [app.n2.Value-0.5,app.n2.Value-0.5],'Color','green');
            elseif app.n4.Value ==1
                supportddata(temp,1)=plot(app.axe,[app.n1.Value,app.n1.Value-0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(temp,2)=plot(app.axe,[app.n1.Value,app.n1.Value+0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(temp,3)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value+0.3], [app.n2.Value-0.5,app.n2.Value-0.5],'Color','green');
                supportddata(temp,4)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value+0.3], [app.n2.Value-0.7,app.n2.Value-0.7],'Color','green');
            elseif app.n3.Value ==1
                supportddata(temp,1)=plot(app.axe,[app.n1.Value,app.n1.Value-0.3], [app.n2.Value,app.n2.Value+0.5],'Color','green');
                supportddata(temp,2)=plot(app.axe,[app.n1.Value,app.n1.Value-0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(temp,3)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value-0.3], [app.n2.Value+0.5,app.n2.Value-0.5],'Color','green');
                supportddata(temp,4)=plot(app.axe,[app.n1.Value-0.5,app.n1.Value-0.5], [app.n2.Value+0.5,app.n2.Value-0.5],'Color','green');
            end
            
            temp=0;
            varx=0;
            vary=0;
            supx=0;
            supy=0;
            app.Button2.Enable = 'off';
            
        end

        % Value changed function: memberlist
        function memberlistValueChanged(app, event)
            global temp;
            global memberdata;
            value = str2double(app.memberlist.Value);
            temp=value;
            app.m1.Value = memberdata(value,9);
            app.m2.Value = memberdata(value,10);
            app.Button4.Enable = 'on';
            
        end

        % Button pushed function: Button10
        function Button10ButtonPushed(app, event)
            
            app.Button10.Enable = 'off';
            
            app.memberfb.Items = app.memberlist.Items;
        end

        % Button pushed function: Button9
        function Button9ButtonPushed(app, event)
            
            global change;
            global memberdata;
            selection=change;
            memberdata(selection,20)=app.nn1.Value;
            memberdata(selection,21)=app.nn2.Value;
            
            change=0;
        end

        % Value changed function: memberfb
        function memberfbValueChanged(app, event)
            global change;
            value = str2double(app.memberfb.Value);
            change=value;
            
            app.Button9.Enable = 'on';
        end

        % Button pushed function: Button11
        function Button11ButtonPushed(app, event)
            global memberdata;
            global nodedata;
            global member;
            global node;
            global swa;
            global sw;
            global loaded;
            loaded =1;
            member=16;
            node=13;
            swa =1;
            sw=23.6;
            
            memberdata=[0,0,0,0,25000000,0.25,6250000,130208.3325,1,4,0,0,0,0,0,0,0,0,0,0,6.66;
                0,0,0,0,25000000,0.25,6250000,130208.3325,2,5,0,0,0,0,0,0,0,0,0,0,0;
                0,0,0,0,25000000,0.25,6250000,130208.3325,3,6,0,0,0,0,0,0,0,0,0,0,0;
                0,0,0,0,25000000,0.25,6250000,130208.3325,4,5,0,0,0,0,0,0,0,0,0,0,0;
                0,0,0,0,25000000,0.25,6250000,130208.3325,5,6,0,0,0,0,0,0,0,0,0,0,0;
                0,0,0,0,25000000,0.25,6250000,130208.3325,4,7,0,0,0,0,0,0,0,0,0,6.66,13.33;
                0,0,0,0,25000000,0.25,6250000,130208.3325,5,8,0,0,0,0,0,0,0,0,0,0,0;
                0,0,0,0,25000000,0.25,6250000,130208.3325,6,9,0,0,0,0,0,0,0,0,0,0,0;
                0,0,0,0,25000000,0.25,6250000,130208.3325,7,8,0,0,0,0,0,0,0,0,0,0,0;
                0,0,0,0,25000000,0.25,6250000,130208.3325,8,9,0,0,0,0,0,0,0,0,0,0,0;
                0,0,0,0,25000000,0.25,6250000,130208.3325,9,10,0,0,0,0,0,0,0,0,0,0,0;
                0,0,0,0,25000000,0.25,6250000,130208.3325,7,11,0,0,0,0,0,0,0,0,0,13.33,20;
                0,0,0,0,25000000,0.25,6250000,130208.3325,8,12,0,0,0,0,0,0,0,0,0,0,0;
                0,0,0,0,25000000,0.25,6250000,130208.3325,9,13,0,0,0,0,0,0,0,0,0,0,0;
                0,0,0,0,25000000,0.25,6250000,130208.3325,11,12,0,0,0,0,0,0,0,0,0,0,0;
                0,0,0,0,25000000,0.25,6250000,130208.3325,12,13,0,0,0,0,0,0,0,0,0,0,0];
            
            nodedata=[0,0,1,1,1,0,0,0;
                4,0,1,1,1,0,0,0;
                8,0,1,1,1,0,0,0;
                0,3,0,0,0,0,0,0;
                4,3,0,0,0,0,0,0;
                8,3,0,0,0,0,0,0;
                0,6,0,0,0,0,0,0;
                4,6,0,0,0,0,0,0;
                8,6,0,0,0,0,0,0;
                11,6,0,0,0,0,-10,0;
                0,9,0,0,0,0,0,0;
                4,9,0,0,0,0,0,0;
                8,9,0,0,0,0,0,0;];
            
            f = warndlg('Homework loaded PRESS SOLVE','Loading');
            
            
        end

        % Button pushed function: Button12
        function Button12ButtonPushed(app, event)
            global swa;
            global sw;
            swa=1;
            sw=app.sww.Value;
            app.Button12.Enable = 'off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Beam Solver By sfktrkl';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Nodes';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed, true);
            app.Button.Text = 'Add';
            app.Button2 = uibutton(app.Tab, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2ButtonPushed, true);
            app.Button2.Enable = 'off';
            app.Button2.Text = 'Change';
            app.LabelNumericEditField3 = uilabel(app.Tab);
            app.LabelNumericEditField3.Text = 'X Coordinate';
            app.n1 = uieditfield(app.Tab, 'numeric');
            app.LabelNumericEditField4 = uilabel(app.Tab);
            app.LabelNumericEditField4.Text = 'Y Coordinate';
            app.n2 = uieditfield(app.Tab, 'numeric');
            app.LabelNumericEditField5 = uilabel(app.Tab);
            app.LabelNumericEditField5.Text = 'X Direction';
            app.n3 = uieditfield(app.Tab, 'numeric');
            app.n3.Value = 1;
            app.Label = uilabel(app.Tab);
            app.Label.Text = 'Y Direction';
            app.n4 = uieditfield(app.Tab, 'numeric');
            app.n4.Value = 1;
            app.Label2 = uilabel(app.Tab);
            app.Label2.Text = 'Support Conditions - Fixities';
            app.Label3 = uilabel(app.Tab);
            app.Label3.Text = 'Force - Moment Conditions';
            app.Label4 = uilabel(app.Tab);
            app.Label4.Text = 'X Direction';
            app.n5 = uieditfield(app.Tab, 'numeric');
            app.Label5 = uilabel(app.Tab);
            app.Label5.Text = 'Y Direction';
            app.n6 = uieditfield(app.Tab, 'numeric');
            app.LabelNumericEditField6 = uilabel(app.Tab);
            app.LabelNumericEditField6.Enable = 'off';
            app.LabelNumericEditField6.Text = 'Node Count';
            app.count = uieditfield(app.Tab, 'numeric');
            app.count.Enable = 'off';
            app.count.Value = 1;
            app.LabelListBox = uilabel(app.Tab);
            app.LabelListBox.Enable = 'off';
            app.LabelListBox.Text = 'Nodes';
            app.nodelist = uilistbox(app.Tab);
            app.nodelist.ValueChangedFcn = createCallbackFcn(app, @nodelistValueChanged, true);
            app.nodelist.Enable = 'off';
            app.LabelNumericEditField12 = uilabel(app.Tab);
            app.LabelNumericEditField12.Text = 'Z direction';
            app.n7 = uieditfield(app.Tab, 'numeric');
            app.n7.Value = 1;
            app.LabelNumericEditField13 = uilabel(app.Tab);
            app.LabelNumericEditField13.Text = 'Moment';
            app.n8 = uieditfield(app.Tab, 'numeric');
            app.Label11 = uilabel(app.Tab);
            app.Label11.Text = 'Node Coordinates';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = 'First add nodes according to their coordinates, fixities and their point loads if there is any.';
            app.ThenaddmembersfrommemberstabLabel = uilabel(app.Tab);
            app.ThenaddmembersfrommemberstabLabel.Text = 'Then, add members from members tab.';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Members';
            app.Button3 = uibutton(app.Tab2, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3ButtonPushed, true);
            app.Button3.Text = 'Add';
            app.Button4 = uibutton(app.Tab2, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4ButtonPushed, true);
            app.Button4.Enable = 'off';
            app.Button4.Text = 'Change';
            app.LabelNumericEditField = uilabel(app.Tab2);
            app.LabelNumericEditField.Text = 'First Node';
            app.m1 = uieditfield(app.Tab2, 'numeric');
            app.m1.Value = 1;
            app.LabelNumericEditField2 = uilabel(app.Tab2);
            app.LabelNumericEditField2.Text = 'Second Node';
            app.m2 = uieditfield(app.Tab2, 'numeric');
            app.m2.Value = 2;
            app.LabelNumericEditField7 = uilabel(app.Tab2);
            app.LabelNumericEditField7.Text = 'Member E';
            app.m3 = uieditfield(app.Tab2, 'numeric');
            app.m3.Value = 25000000;
            app.LabelNumericEditField8 = uilabel(app.Tab2);
            app.LabelNumericEditField8.Enable = 'off';
            app.LabelNumericEditField8.Text = 'Member Count';
            app.count2 = uieditfield(app.Tab2, 'numeric');
            app.count2.Enable = 'off';
            app.count2.Value = 1;
            app.LabelListBox2 = uilabel(app.Tab2);
            app.LabelListBox2.Enable = 'off';
            app.LabelListBox2.Text = 'Members';
            app.memberlist = uilistbox(app.Tab2);
            app.memberlist.ValueChangedFcn = createCallbackFcn(app, @memberlistValueChanged, true);
            app.memberlist.Enable = 'off';
            app.Label12 = uilabel(app.Tab2);
            app.Label12.Text = 'Member Nodes';
            app.Label13 = uilabel(app.Tab2);
            app.Label13.Text = 'Member Properties';
            app.LabelNumericEditField14 = uilabel(app.Tab2);
            app.LabelNumericEditField14.Text = 'Height';
            app.m4 = uieditfield(app.Tab2, 'numeric');
            app.m4.Value = 0.5;
            app.LabelNumericEditField15 = uilabel(app.Tab2);
            app.LabelNumericEditField15.Text = 'Witdh';
            app.m5 = uieditfield(app.Tab2, 'numeric');
            app.m5.Value = 0.5;
            app.LabelNumericEditField16 = uilabel(app.Tab2);
            app.LabelNumericEditField16.Enable = 'off';
            app.LabelNumericEditField16.Text = 'Area';
            app.m6 = uieditfield(app.Tab2, 'numeric');
            app.m6.Enable = 'off';
            app.LabelNumericEditField17 = uilabel(app.Tab2);
            app.LabelNumericEditField17.Enable = 'off';
            app.LabelNumericEditField17.Text = 'Inertia';
            app.m7 = uieditfield(app.Tab2, 'numeric');
            app.m7.Enable = 'off';
            app.AddmembersaccordingtotheirnodesanddeterminetheirpropertiesLabel = uilabel(app.Tab2);
            app.AddmembersaccordingtotheirnodesanddeterminetheirpropertiesLabel.Text = 'Add members according to their nodes and determine their properties.';
            app.ThenadddistributedloadsfromdistributedloadtabifthereisanyLabel = uilabel(app.Tab2);
            app.ThenadddistributedloadsfromdistributedloadtabifthereisanyLabel.Text = 'Then, if there is any distributed load; from distributed load tab add them.';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Distributed Load';
            app.LabelNumericEditField11 = uilabel(app.Tab4);
            app.LabelNumericEditField11.Text = 'Load Node 1';
            app.nn1 = uieditfield(app.Tab4, 'numeric');
            app.Button9 = uibutton(app.Tab4, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @Button9ButtonPushed, true);
            app.Button9.Enable = 'off';
            app.Button9.Text = 'Add/Change';
            app.LabelListBox4 = uilabel(app.Tab4);
            app.LabelListBox4.Text = 'Member List';
            app.memberfb = uilistbox(app.Tab4);
            app.memberfb.ValueChangedFcn = createCallbackFcn(app, @memberfbValueChanged, true);
            app.Button10 = uibutton(app.Tab4, 'push');
            app.Button10.ButtonPushedFcn = createCallbackFcn(app, @Button10ButtonPushed, true);
            app.Button10.Text = 'Create List';
            app.LabelNumericEditField18 = uilabel(app.Tab4);
            app.LabelNumericEditField18.Text = 'Load Node 2';
            app.nn2 = uieditfield(app.Tab4, 'numeric');
            app.nn2.Value = -5;
            app.Label14 = uilabel(app.Tab4);
            app.Label14.Text = 'Buton will be enabled after Member Selection';
            app.Label15 = uilabel(app.Tab4);
            app.Label15.Text = 'Member Distributed Forces Magnitudes';
            app.IfthereisanydistributedloadLabel = uilabel(app.Tab4);
            app.IfthereisanydistributedloadLabel.Text = 'If there is any distributed load;';
            app.CreatethememberlistLabel = uilabel(app.Tab4);
            app.CreatethememberlistLabel.Text = 'Create the member list,';
            app.SelectthememberfromthelistLabel = uilabel(app.Tab4);
            app.SelectthememberfromthelistLabel.Text = 'Select the member from the list,';
            app.AddloadbypressingaddchangebuttonLabel = uilabel(app.Tab4);
            app.AddloadbypressingaddchangebuttonLabel.Text = 'Add load by pressing add/change button.';
            app.IfselfweightisincludedfromselfweighttabitcanbedeterminedLabel = uilabel(app.Tab4);
            app.IfselfweightisincludedfromselfweighttabitcanbedeterminedLabel.Text = 'If self-weight is included, from self-weight tab it can be determined.';
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Self Weight';
            app.LabelNumericEditField19 = uilabel(app.Tab5);
            app.LabelNumericEditField19.Text = 'Unit Weight';
            app.sww = uieditfield(app.Tab5, 'numeric');
            app.sww.Value = 23.6;
            app.Label16 = uilabel(app.Tab5);
            app.Label16.Text = 'Material Properties';
            app.Button12 = uibutton(app.Tab5, 'push');
            app.Button12.ButtonPushedFcn = createCallbackFcn(app, @Button12ButtonPushed, true);
            app.Button12.Text = 'Apply Self Weight';
            app.DeterminetheunitweightandapplyitLabel = uilabel(app.Tab5);
            app.DeterminetheunitweightandapplyitLabel.Text = 'Determine the unit weight and apply it.';
            app.axe = uiaxes(app.UIFigure);
            title(app.axe, 'Structure')
            xlabel(app.axe, 'X Direction m')
            ylabel(app.axe, 'Y Direction m')
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5ButtonPushed, true);
            app.Button5.Text = 'Solve';
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'Solutions are in MATLAB Command Window';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'By sfktrkl';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = {'Green>Supports'; 'Blue>Nodes'; 'Black>Members'; 'Red>Forces'};
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = {'UNITS'; 'kN'; 'm'; 'kN/m2'; 'kN/m3'};
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Text = 'Discretization will shown in another Figure after solution.';
            app.Button11 = uibutton(app.UIFigure, 'push');
            app.Button11.ButtonPushedFcn = createCallbackFcn(app, @Button11ButtonPushed, true);
            app.Button11.Text = 'Load Homework';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BeamApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end