classdef TrussApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % Truss ...
        TabGroup                matlab.ui.container.TabGroup       % Nodes,...
        Tab                     matlab.ui.container.Tab            % Nodes
        Button                  matlab.ui.control.Button           % Add
        Button2                 matlab.ui.control.Button           % Change
        LabelNumericEditField3  matlab.ui.control.Label            % X Coor...
        n1                      matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField4  matlab.ui.control.Label            % Y Coor...
        n2                      matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField5  matlab.ui.control.Label            % X Dire...
        n3                      matlab.ui.control.NumericEditField % [-Inf ...
        Label                   matlab.ui.control.Label            % Y Dire...
        n4                      matlab.ui.control.NumericEditField % [-Inf ...
        Label2                  matlab.ui.control.Label            % Suppor...
        Label3                  matlab.ui.control.Label            % Force ...
        Label4                  matlab.ui.control.Label            % X Dire...
        n5                      matlab.ui.control.NumericEditField % [-Inf ...
        Label5                  matlab.ui.control.Label            % Y Dire...
        n6                      matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField6  matlab.ui.control.Label            % Node C...
        count                   matlab.ui.control.NumericEditField % [-Inf ...
        LabelListBox            matlab.ui.control.Label            % Nodes
        nodelist                matlab.ui.control.ListBox          % Item 1...
        Tab2                    matlab.ui.container.Tab            % Members
        Button3                 matlab.ui.control.Button           % Add
        Button4                 matlab.ui.control.Button           % Change
        LabelNumericEditField   matlab.ui.control.Label            % First ...
        m1                      matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField2  matlab.ui.control.Label            % Second...
        m2                      matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField7  matlab.ui.control.Label            % Member EA
        m3                      matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField8  matlab.ui.control.Label            % Member...
        count2                  matlab.ui.control.NumericEditField % [-Inf ...
        LabelListBox2           matlab.ui.control.Label            % Members
        memberlist              matlab.ui.control.ListBox          % Item 1...
        Tab3                    matlab.ui.container.Tab            % Temper...
        LabelNumericEditField9  matlab.ui.control.Label            % Therma...
        thc1                    matlab.ui.control.NumericEditField % [-Inf ...
        Button6                 matlab.ui.control.Button           % Save C...
        LabelListBox3           matlab.ui.control.Label            % Member...
        memberth                matlab.ui.control.ListBox          % Item 1...
        Button7                 matlab.ui.control.Button           % Create...
        LabelNumericEditField10 matlab.ui.control.Label            % Delta C
        deltac                  matlab.ui.control.NumericEditField % [-Inf ...
        Button8                 matlab.ui.control.Button           % Add/Ch...
        Tab4                    matlab.ui.container.Tab            % Fabric...
        LabelNumericEditField11 matlab.ui.control.Label            % Change m
        deltam                  matlab.ui.control.NumericEditField % [-Inf ...
        Button9                 matlab.ui.control.Button           % Add/Ch...
        LabelListBox4           matlab.ui.control.Label            % Member...
        memberfb                matlab.ui.control.ListBox          % Item 1...
        Button10                matlab.ui.control.Button           % Create...
        axe                     matlab.ui.control.UIAxes           % Structure
        Button5                 matlab.ui.control.Button           % Solve
        Label6                  matlab.ui.control.Label            % Soluti...
        Label7                  matlab.ui.control.Label            % By sfk...
        Label8                  matlab.ui.control.Label            % Green>...
        Label9                  matlab.ui.control.Label            % UNITS
        Label10                 matlab.ui.control.Label            % Discre...
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.nodelist.Items = {};
            app.memberlist.Items = {};
            app.memberth.Items = {};
            app.memberfb.Items = {};
            global isth;
            global isfb;
            isfb=0;
            isth=0;
            global nodedata;
            nodedata=zeros(1,9);
            global node;
            node = 0;
            global memberdata;
            memberdata=zeros(1,17);
            global member;
            member =0;
            hold(app.axe,'on');
            
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            global plotdata;
            global node;
            node = node+1;
            global nodedata;
            global textdata;
            global forceddata;
            global forcetdata;
            global supportddata;
            nodedata(node,:)=[app.n1.Value,app.n2.Value,0,app.n3.Value,app.n4.Value,app.n5.Value,app.n6.Value,0,0];
            if app.n3.Value == 1
            nodedata(node,3)=1;
            elseif app.n4.Value == 1
            nodedata(node,3)=1;
            end
            app.nodelist.Items{node} = int2str(node);
            app.count.Value = node;
            plotdata(node,1)=plot(app.axe,app.n1.Value,app.n2.Value,'.-','Color','blue');
            textdata(node,1)=text(app.axe,app.n1.Value-0.6,app.n2.Value-0.6,int2str(node),'Color','blue','FontSize',16);
            
            if app.n5.Value ~= 0
            forceddata(node,1)=plot(app.axe,[app.n1.Value-0.1,app.n1.Value-0.9], [app.n2.Value,app.n2.Value],'Color','red');
            forcetdata(node,1)=text(app.axe,app.n1.Value-1.2,app.n2.Value,{int2str(app.n5.Value),' kN'},'Color','red','FontSize',16);
            forceddata(node,3)=plot(app.axe,[app.n1.Value-0.1,app.n1.Value-0.2], [app.n2.Value,app.n2.Value-0.2],'Color','red');
            forceddata(node,4)=plot(app.axe,[app.n1.Value-0.1,app.n1.Value-0.2], [app.n2.Value,app.n2.Value+0.2],'Color','red');
            end
            if app.n6.Value ~= 0
            forceddata(node,2)=plot(app.axe,[app.n1.Value,app.n1.Value], [app.n2.Value-0.1,app.n2.Value-0.9],'Color','red');
            forcetdata(node,2)=text(app.axe,app.n1.Value,app.n2.Value-1.2,{int2str(app.n6.Value),' kN'},'Color','red','FontSize',16);
            forceddata(node,5)=plot(app.axe,[app.n1.Value,app.n1.Value-0.1], [app.n2.Value-0.1,app.n2.Value-0.2],'Color','red');
            forceddata(node,6)=plot(app.axe,[app.n1.Value,app.n1.Value+0.1], [app.n2.Value-0.1,app.n2.Value-0.2],'Color','red');
            end
            
            if app.n3.Value ==1 && app.n4.Value ==1
                supportddata(node,1)=plot(app.axe,[app.n1.Value,app.n1.Value-0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(node,2)=plot(app.axe,[app.n1.Value,app.n1.Value+0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(node,3)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value+0.3], [app.n2.Value-0.5,app.n2.Value-0.5],'Color','green');
                supportddata(node,4)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value+0.3], [app.n2.Value-0.5,app.n2.Value-0.5],'Color','green');
            elseif app.n4.Value ==1
                supportddata(node,1)=plot(app.axe,[app.n1.Value,app.n1.Value-0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(node,2)=plot(app.axe,[app.n1.Value,app.n1.Value+0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(node,3)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value+0.3], [app.n2.Value-0.5,app.n2.Value-0.5],'Color','green');
                supportddata(node,4)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value+0.3], [app.n2.Value-0.7,app.n2.Value-0.7],'Color','green');
            elseif app.n3.Value ==1
                supportddata(node,1)=plot(app.axe,[app.n1.Value,app.n1.Value-0.3], [app.n2.Value,app.n2.Value+0.5],'Color','green');
                supportddata(node,2)=plot(app.axe,[app.n1.Value,app.n1.Value-0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(node,3)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value-0.3], [app.n2.Value+0.5,app.n2.Value-0.5],'Color','green');
                supportddata(node,4)=plot(app.axe,[app.n1.Value-0.5,app.n1.Value-0.5], [app.n2.Value+0.5,app.n2.Value-0.5],'Color','green');
            end
            
            gx=0;
for i=1:node
    if gx < nodedata(i,1)
        gx=nodedata(i,1);
    end
end

gy=0;
for i=1:node
    if gy < nodedata(i,2)
        gy=nodedata(i,2);
    end

end

app.axe.XLim = [-3,gx+3];
app.axe.YLim = [-3,gy+3];
            
        end

        % Button3 button pushed function
        function Button3ButtonPushed(app)
            global plotdata;
            global member;
            member = member+1;
            global memberdata;
            global nodedata;
            global textdata;
            k1=app.m1.Value;
            k2=app.m2.Value;
            x1=nodedata(k1,1);
            y1=nodedata(k1,2);
            x2=nodedata(k2,1);
            y2=nodedata(k2,2);
            x=x2-x1;
            y=y2-y1;
            l=sqrt(x*x+y*y);
            c=x/l;
            s=y/l;
            cs=c*s;
            c2=c*c;
            s2=s*s;
           
            memberdata(member,:)=[x1,y1,x2,y2,c2,s2,cs,app.m3.Value,k1,k2,0,0,0,0,l,c,s];
            app.memberlist.Items{member} = int2str(member);
            plotdata(member,2)=plot(app.axe,[memberdata(member,1),memberdata(member,3)],[memberdata(member,2),memberdata(member,4)],'Color','black');
            xort=(x2+x1)/2;
            yort=(y2+y1)/2;
            textdata(member,2)=text(app.axe,xort-0.3,yort-0.3,int2str(member),'Color','black','FontSize',16);
            app.count2.Value = member;
            
            
        end

        % Button5 button pushed function
        function Button5ButtonPushed(app)
            global dofdata;
            global memberdata;
            global member;
            global nodedata;
            global node
            global KG;
            global KL;
            global forcedata;
            global tempfdata;
            global errorfdata;
            global isfb;
            global isth;
            
            if isfb == 0
                errorfdata=zeros(member,1);
            end
            if isth == 0
                tempfdata=zeros(member,1);
            end
            
            dofdata=zeros(node,2);
                sk = 0;
            for i=1:node
                for j=4:5
                    if nodedata(i,j) == 0
                        sk = sk+1;
                        dofdata(i,j-3)=sk;
                    end
                end
            end
            KG=zeros(sk,sk);
            for i=1:member
                for j=1:node
                    if memberdata(i,1)== nodedata(j,1) && memberdata(i,2)== nodedata(j,2)
                        memberdata(i,9) = j;
                    elseif memberdata(i,3)== nodedata(j,1) && memberdata(i,4)== nodedata(j,2)
                        memberdata(i,10) = j;
                    end
                end
            end
KL= zeros(4,4,member);
for i=1:member
    KL(1,1,i)=memberdata(i,8)/memberdata(i,15)*memberdata(i,5);
    KL(1,2,i)=memberdata(i,8)/memberdata(i,15)*memberdata(i,7);
    KL(1,3,i)=-memberdata(i,8)/memberdata(i,15)*memberdata(i,5);
    KL(1,4,i)=-memberdata(i,8)/memberdata(i,15)*memberdata(i,7);
    KL(2,1,i)=memberdata(i,8)/memberdata(i,15)*memberdata(i,7);
    KL(2,2,i)=memberdata(i,8)/memberdata(i,15)*memberdata(i,6);
    KL(2,3,i)=-memberdata(i,8)/memberdata(i,15)*memberdata(i,7);
    KL(2,4,i)=-memberdata(i,8)/memberdata(i,15)*memberdata(i,6);
    KL(3,1,i)=-memberdata(i,8)/memberdata(i,15)*memberdata(i,5);
    KL(3,2,i)=-memberdata(i,8)/memberdata(i,15)*memberdata(i,7);
    KL(3,3,i)=memberdata(i,8)/memberdata(i,15)*memberdata(i,5);
    KL(3,4,i)=memberdata(i,8)/memberdata(i,15)*memberdata(i,7);
    KL(4,1,i)=-memberdata(i,8)/memberdata(i,15)*memberdata(i,7);
    KL(4,2,i)=-memberdata(i,8)/memberdata(i,15)*memberdata(i,6);
    KL(4,3,i)=memberdata(i,8)/memberdata(i,15)*memberdata(i,7);
    KL(4,4,i)=memberdata(i,8)/memberdata(i,15)*memberdata(i,6);
end


%pairs the members with dofs
for i=1:member
        memberdata(i,11)=dofdata(memberdata(i,9),1);
        memberdata(i,12)=dofdata(memberdata(i,9),2);
        memberdata(i,13)=dofdata(memberdata(i,10),1);
        memberdata(i,14)=dofdata(memberdata(i,10),2);
end


%enters Kglobal, parts of Kmember's
for i=1:member
    for x=1:4
        for y=1:4
            if memberdata(i,10+x) ~= 0 && memberdata(i,10+y) ~= 0
                KG(memberdata(i,10+x),memberdata(i,10+y))=KL(x,y,i)+KG(memberdata(i,10+x),memberdata(i,10+y));
            end
        end
    end
end


for i=1:node
    nodedata(i,8)=dofdata(i,1);
    nodedata(i,9)=dofdata(i,2);
end

%creates force matrix
forcedata=zeros(sk,1);
for i=1:node
    for j=1:2
        if dofdata(i,j) ~= 0 
            if j==1
            forcedata(dofdata(i,j))=forcedata(dofdata(i,j))+nodedata(i,6);
            elseif j==2
            forcedata(dofdata(i,j))=forcedata(dofdata(i,j))+nodedata(i,7);
            end
        end
    end
end


%adds temperature and fabrication errors as FEM
for i=1:member
    if tempfdata(i,1) ~= 0 || errorfdata(i,1) ~= 0
        for j=11:14
            if memberdata(i,j) ~= 0
                if j == 11
                forcedata(memberdata(i,j),1) = forcedata(memberdata(i,j),1) + -tempfdata(i,1)*memberdata(i,16)+ -errorfdata(i,1)*memberdata(i,16);
                elseif j ==12
                forcedata(memberdata(i,j),1) = forcedata(memberdata(i,j),1) + -tempfdata(i,1)*memberdata(i,17)+ -errorfdata(i,1)*memberdata(i,17);    
                elseif j ==13
                forcedata(memberdata(i,j),1) = forcedata(memberdata(i,j),1) + tempfdata(i,1)*memberdata(i,16)+ errorfdata(i,1)*memberdata(i,16);
                elseif j== 14
                forcedata(memberdata(i,j),1) = forcedata(memberdata(i,j),1) + tempfdata(i,1)*memberdata(i,17)+ errorfdata(i,1)*memberdata(i,17);
                end
            end
        end
    end
end

%solves for u
u=linsolve(KG,forcedata);

%arranges deflection matrices for every member
KF=zeros(member,4);
KLD=zeros(4,1,member);
for i=1:member
    for j=11:14
        if memberdata(i,j) ~= 0
            KLD(j-10,1,i)= u(memberdata(i,j));
        end
    end
end

%calculates member forces using partitioning
for i=1:member
    
    mult=[-memberdata(i,16),-memberdata(i,17),memberdata(i,16),memberdata(i,17)] * KLD(:,:,i);
    KF(i,1)=i;
    KF(i,2)=memberdata(i,8)/memberdata(i,15)*mult;
    KF(i,3)=-tempfdata(i,1);
    KF(i,4)=-errorfdata(i,1);
    KF(i,5)=KF(i,2)+KF(i,3)+KF(i,4);
end

tablee = table(KF(:,1),KF(:,2),KF(:,3),KF(:,4),KF(:,5));
tablee.Properties.VariableNames = {'Member_No','Force_kN','Force_Temp_kN','Force_F_Error_kN','Total_kN'};

tableeee = table(KF(:,1),KF(:,5),memberdata(:,15));
tableeee.Properties.VariableNames = {'Member_No','Force_kN','Member_Length_m'};

KS=zeros(node,1);
for i=1:node
    KS(i,1)=i;
end

%calculates support reactions using member forces
KSF=zeros(node,2);
for i=1:member
    for j=9:10;
        if j==9
        KSF(memberdata(i,j),:)=[memberdata(i,16)*-KF(i,5),memberdata(i,17)*-KF(i,5)]+KSF(memberdata(i,j),:);
        elseif j==10
        KSF(memberdata(i,j),:)=[memberdata(i,16)*KF(i,5),memberdata(i,17)*KF(i,5)]+KSF(memberdata(i,j),:);
        end
    end
end


j=1;
for i=1:node
    if nodedata(i,3) == 0
        KSF(j,:)=[];
        KS(j,:)=[];
        j=j-1;
    end
    j=j+1;
end

tablee2 = table(KS(:,1),KSF(:,1),KSF(:,2));
tablee2.Properties.VariableNames = {'Node_No','X_dir_Force_kN','Y_dir_Force_kN'};

DS=zeros(sk,2);

cnt=1;
for i=1:node
    for j=1:2
        if dofdata(i,j) ~= 0
            DS(cnt,1)=i;
            DS(cnt,2)=dofdata(i,j);
            cnt=cnt+1;
        end
    end
end


tablee3 = table(DS(:,1),DS(:,2),u(:,1));
tablee3.Properties.VariableNames = {'Node_No','Dof_No','Displacement_m'};

tablee4 = table(DS(:,1),DS(:,2),forcedata(:,1));
tablee4.Properties.VariableNames = {'Node_No','Dof_No','Force_kN'};


fprintf('\nMember Global Matrices\n');
disp(KL);
fprintf('\nK(structure)\n');
disp(KG);
fprintf('\nForce Matrix\n');
disp(tablee4);
fprintf('\nMember Forces\n');
disp(tablee);
fprintf('\nSupport Reactions\n');
disp(tablee2);
fprintf('\nAnswer\n');
disp(tablee3);

hold on;
title('Discretization');
%calculates plot area
gx=0;
for i=1:member
    if gx < memberdata(i,3)
        gx=memberdata(i,3);
    end
    if gx < memberdata(i,1)
        gx=memberdata(i,1);
    end
end

gy=0;
for i=1:member
    if gy < memberdata(i,4)
        gy=memberdata(i,4);
    end
    if gy < memberdata(i,2)
        gy=memberdata(i,2);
    end
end

xlim([-2,gx+2]);
ylim([-2,gy+2]);

%plots members
for i=1:member
    line([memberdata(i,1),memberdata(i,3)],[memberdata(i,2),memberdata(i,4)]);
end

%plots dofs
for i=1:node
    for j=1:2
        if dofdata(i,j) ~= 0
            if j==1
                quiver(nodedata(i,1)-0.5,nodedata(i,2)+0.5,0.5,0,'MaxHeadSize',1.5,'Color','r','LineWidth',2);
                text(nodedata(i,1)+0.02,nodedata(i,2)+0.5,int2str(dofdata(i,j)));
            else
                quiver(nodedata(i,1)-0.5,nodedata(i,2)+0.5,0,0.5,'MaxHeadSize',1.5,'Color','r','LineWidth',2);
                text(nodedata(i,1)-0.5,nodedata(i,2)+1.02,int2str(dofdata(i,j)));
            end
        end
    end
end

        end

        % nodelist value changed function
        function nodelistValueChanged(app)
            global nodedata;
            global temp;
            global varx;
            global vary;
            global supx;
            global supy;
            value = str2double(app.nodelist.Value);
            temp=value;
            app.n1.Value = nodedata(value,1);
            app.n2.Value = nodedata(value,2);
            app.n3.Value = nodedata(value,4);
            app.n4.Value = nodedata(value,5);
            app.n5.Value = nodedata(value,6);
            app.n6.Value = nodedata(value,7);
            if app.n5.Value ~= 0
                varx =1;
            end
            if app.n6.Value ~= 0
                vary =1;
            end
            if app.n3.Value ~= 0
                supx =1;
            end
            if app.n4.Value ~= 0
                supy =1;
            end
            app.Button2.Enable = 'on';
        end

        % Button4 button pushed function
        function Button4ButtonPushed(app)
            global plotdata;
            global member;
            global memberdata;
            global nodedata;
            global temp;
            global textdata;
            k1=app.m1.Value;
            k2=app.m2.Value;
            x1=nodedata(k1,1);
            y1=nodedata(k1,2);
            x2=nodedata(k2,1);
            y2=nodedata(k2,2);
            x=x2-x1;
            y=y2-y1;
            l=sqrt(x*x+y*y);
            c=x/l;
            s=y/l;
            cs=c*s;
            c2=c*c;
            s2=s*s;
           
            memberdata(temp,:)=[x1,y1,x2,y2,c2,s2,cs,app.m3.Value,k1,k2,0,0,0,0,l,c,s];
            delete(plotdata(temp,2));
            delete(textdata(temp,2));
            plotdata(temp,2)=plot(app.axe,[memberdata(member,1),memberdata(member,3)],[memberdata(member,2),memberdata(member,4)],'Color','black');
            xort=(x2+x1)/2;
            yort=(y2+y1)/2;
            
            textdata(member,2)=text(app.axe,xort-0.3,yort-0.3,int2str(temp),'Color','black','FontSize',16);
            
            app.Button4.Enable = 'off';


        end

        % Button2 button pushed function
        function Button2ButtonPushed(app)
            global plotdata;
            global nodedata;
            global temp;
            global textdata;
            global forceddata;
            global forcetdata;
            global varx;
            global vary;
            global supportddata;
            global supx;
            global supy;
            
            nodedata(temp,:)=[app.n1.Value,app.n2.Value,0,app.n3.Value,app.n4.Value,app.n5.Value,app.n6.Value,0,0];
            if app.n3.Value == 1
            nodedata(temp,3)=1;
            elseif app.n4.Value == 1
            nodedata(temp,3)=1;
            end
            delete(plotdata(temp,1));
            delete(textdata(temp,1));
            if varx ==1 
            delete(forceddata(temp,1));
            delete(forcetdata(temp,1));
            delete(forceddata(temp,3));
            delete(forceddata(temp,4));
            end
            if vary ==1
            delete(forceddata(temp,5));
            delete(forceddata(temp,6));
            delete(forcetdata(temp,2));
            delete(forceddata(temp,2));
            end
            if supx==1||supy==1
            delete(supportddata(temp,1));
            delete(supportddata(temp,2));
            delete(supportddata(temp,3));
            delete(supportddata(temp,4));
            end
            plotdata(temp,1)=plot(app.axe,app.n1.Value,app.n2.Value,'.-','Color','blue');
            textdata(temp,1)=text(app.axe,app.n1.Value-0.6,app.n2.Value-0.6,int2str(temp),'Color','blue','FontSize',16);
            
            
            if app.n5.Value ~= 0
            forceddata(temp,1)=plot(app.axe,[app.n1.Value-0.1,app.n1.Value-0.9], [app.n2.Value,app.n2.Value],'Color','red');
            forcetdata(temp,1)=text(app.axe,app.n1.Value-1.2,app.n2.Value,{int2str(app.n5.Value),' kN'},'Color','red','FontSize',16);
            forceddata(temp,3)=plot(app.axe,[app.n1.Value-0.1,app.n1.Value-0.2], [app.n2.Value,app.n2.Value-0.2],'Color','red');
            forceddata(temp,4)=plot(app.axe,[app.n1.Value-0.1,app.n1.Value-0.2], [app.n2.Value,app.n2.Value+0.2],'Color','red');
            end
            if app.n6.Value ~= 0
            forceddata(temp,2)=plot(app.axe,[app.n1.Value,app.n1.Value], [app.n2.Value-0.1,app.n2.Value-0.9],'Color','red');
            forcetdata(temp,2)=text(app.axe,app.n1.Value,app.n2.Value-1.2,{int2str(app.n6.Value),' kN'},'Color','red','FontSize',16);
            forceddata(temp,5)=plot(app.axe,[app.n1.Value,app.n1.Value-0.1], [app.n2.Value-0.1,app.n2.Value-0.2],'Color','red');
            forceddata(temp,6)=plot(app.axe,[app.n1.Value,app.n1.Value+0.1], [app.n2.Value-0.1,app.n2.Value-0.2],'Color','red');
            end
            
            if app.n3.Value ==1 && app.n4.Value ==1
                supportddata(temp,1)=plot(app.axe,[app.n1.Value,app.n1.Value-0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(temp,2)=plot(app.axe,[app.n1.Value,app.n1.Value+0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(temp,3)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value+0.3], [app.n2.Value-0.5,app.n2.Value-0.5],'Color','green');
                supportddata(temp,4)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value+0.3], [app.n2.Value-0.5,app.n2.Value-0.5],'Color','green');
            elseif app.n4.Value ==1
                supportddata(temp,1)=plot(app.axe,[app.n1.Value,app.n1.Value-0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(temp,2)=plot(app.axe,[app.n1.Value,app.n1.Value+0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(temp,3)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value+0.3], [app.n2.Value-0.5,app.n2.Value-0.5],'Color','green');
                supportddata(temp,4)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value+0.3], [app.n2.Value-0.7,app.n2.Value-0.7],'Color','green');
            elseif app.n3.Value ==1
                supportddata(temp,1)=plot(app.axe,[app.n1.Value,app.n1.Value-0.3], [app.n2.Value,app.n2.Value+0.5],'Color','green');
                supportddata(temp,2)=plot(app.axe,[app.n1.Value,app.n1.Value-0.3], [app.n2.Value,app.n2.Value-0.5],'Color','green');
                supportddata(temp,3)=plot(app.axe,[app.n1.Value-0.3,app.n1.Value-0.3], [app.n2.Value+0.5,app.n2.Value-0.5],'Color','green');
                supportddata(temp,4)=plot(app.axe,[app.n1.Value-0.5,app.n1.Value-0.5], [app.n2.Value+0.5,app.n2.Value-0.5],'Color','green');
            end
            
            temp=0;
            varx=0;
            vary=0;
            supx=0;
            supy=0;
            app.Button2.Enable = 'off';
            
        end

        % memberlist value changed function
        function memberlistValueChanged(app)
            global temp;
            global memberdata;
            value = str2double(app.memberlist.Value);
            temp=value;
            app.m1.Value = memberdata(value,9);
            app.m2.Value = memberdata(value,10);
            app.m3.Value = memberdata(value,8);
            app.Button4.Enable = 'on';
            
        end

        % Button6 button pushed function
        function Button6ButtonPushed(app)
            global thc;
            thc = app.thc1.Value;
            app.Button8.Enable = 'on';
        end

        % Button7 button pushed function
        function Button7ButtonPushed(app)
            global isth;
            global tempfdata;
            global member;
            global tempkdata;
            tempfdata=zeros(member,1);
            tempkdata=zeros(member,1);
            isth=1;
            app.memberth.Items = app.memberlist.Items;
        end

        % Button8 button pushed function
        function Button8ButtonPushed(app)
            global tempfdata;
            global memberdata;
            global tempkdata;
            global thc;
            global change;
            selection = change;
            tempfdata(selection,1)=thc*memberdata(selection,8)*app.deltac.Value;
            tempkdata(selection,1)=app.deltac.Value;
        end

        % memberth value changed function
        function memberthValueChanged(app)
            global tempkdata;
            global change;
            app.deltac.Enable = 'on';
            value = str2double(app.memberth.Value);
            change = value;
            app.deltac.Value=tempkdata(change,1);
            
        end

        % Button10 button pushed function
        function Button10ButtonPushed(app)
            global isfb;
            global errorfdata;
            global member;
            global errorkdata;
            app.Button9.Enable = 'on';
            errorfdata=zeros(member,1);
            errorkdata=zeros(member,1);
            isfb=1;
            app.memberfb.Items = app.memberlist.Items;
        end

        % Button9 button pushed function
        function Button9ButtonPushed(app)
            global errorfdata;
            global memberdata;
            global errorkdata;
            global change;
            selection = change;
            errorfdata(selection,1)=memberdata(selection,8)*app.deltam.Value/memberdata(selection,15);
            errorkdata(selection,1)=app.deltac.Value;
        end

        % memberfb value changed function
        function memberfbValueChanged(app)

            global errorkdata;
            global change;
            app.deltam.Enable = 'on';
            value = str2double(app.memberfb.Value);
            change = value;
            app.deltam.Value=errorkdata(value,1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Truss Solver By sfktrkl';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Nodes';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = 'Add';
            app.Button2 = uibutton(app.Tab, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2ButtonPushed);
            app.Button2.Enable = 'off';
            app.Button2.Text = 'Change';
            app.LabelNumericEditField3 = uilabel(app.Tab);
            app.LabelNumericEditField3.Text = 'X Coordinate';
            app.n1 = uieditfield(app.Tab, 'numeric');
            app.LabelNumericEditField4 = uilabel(app.Tab);
            app.LabelNumericEditField4.Text = 'Y Coordinate';
            app.n2 = uieditfield(app.Tab, 'numeric');
            app.LabelNumericEditField5 = uilabel(app.Tab);
            app.LabelNumericEditField5.Text = 'X Direction';
            app.n3 = uieditfield(app.Tab, 'numeric');
            app.Label = uilabel(app.Tab);
            app.Label.Text = 'Y Direction';
            app.n4 = uieditfield(app.Tab, 'numeric');
            app.Label2 = uilabel(app.Tab);
            app.Label2.Text = 'Support Conditions - Fixities';
            app.Label3 = uilabel(app.Tab);
            app.Label3.Text = 'Force Conditions';
            app.Label4 = uilabel(app.Tab);
            app.Label4.Text = 'X Direction';
            app.n5 = uieditfield(app.Tab, 'numeric');
            app.Label5 = uilabel(app.Tab);
            app.Label5.Text = 'Y Direction';
            app.n6 = uieditfield(app.Tab, 'numeric');
            app.LabelNumericEditField6 = uilabel(app.Tab);
            app.LabelNumericEditField6.Enable = 'off';
            app.LabelNumericEditField6.Text = 'Node Count';
            app.count = uieditfield(app.Tab, 'numeric');
            app.count.Enable = 'off';
            app.count.Value = 1;
            app.LabelListBox = uilabel(app.Tab);
            app.LabelListBox.Text = 'Nodes';
            app.nodelist = uilistbox(app.Tab);
            app.nodelist.ValueChangedFcn = createCallbackFcn(app, @nodelistValueChanged);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Members';
            app.Button3 = uibutton(app.Tab2, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3ButtonPushed);
            app.Button3.Text = 'Add';
            app.Button4 = uibutton(app.Tab2, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4ButtonPushed);
            app.Button4.Enable = 'off';
            app.Button4.Text = 'Change';
            app.LabelNumericEditField = uilabel(app.Tab2);
            app.LabelNumericEditField.Text = 'First Node';
            app.m1 = uieditfield(app.Tab2, 'numeric');
            app.LabelNumericEditField2 = uilabel(app.Tab2);
            app.LabelNumericEditField2.Text = 'Second Node';
            app.m2 = uieditfield(app.Tab2, 'numeric');
            app.LabelNumericEditField7 = uilabel(app.Tab2);
            app.LabelNumericEditField7.Text = 'Member EA';
            app.m3 = uieditfield(app.Tab2, 'numeric');
            app.m3.Value = 200000;
            app.LabelNumericEditField8 = uilabel(app.Tab2);
            app.LabelNumericEditField8.Enable = 'off';
            app.LabelNumericEditField8.Text = 'Member Count';
            app.count2 = uieditfield(app.Tab2, 'numeric');
            app.count2.Enable = 'off';
            app.count2.Value = 1;
            app.LabelListBox2 = uilabel(app.Tab2);
            app.LabelListBox2.Text = 'Members';
            app.memberlist = uilistbox(app.Tab2);
            app.memberlist.ValueChangedFcn = createCallbackFcn(app, @memberlistValueChanged);
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'Temperature Change';
            app.LabelNumericEditField9 = uilabel(app.Tab3);
            app.LabelNumericEditField9.Text = 'Thermal Coefficient';
            app.thc1 = uieditfield(app.Tab3, 'numeric');
            app.Button6 = uibutton(app.Tab3, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @Button6ButtonPushed);
            app.Button6.Text = 'Save Coefficient';
            app.LabelListBox3 = uilabel(app.Tab3);
            app.LabelListBox3.Text = 'Member List';
            app.memberth = uilistbox(app.Tab3);
            app.memberth.ValueChangedFcn = createCallbackFcn(app, @memberthValueChanged);
            app.Button7 = uibutton(app.Tab3, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @Button7ButtonPushed);
            app.Button7.Text = 'Create List';
            app.LabelNumericEditField10 = uilabel(app.Tab3);
            app.LabelNumericEditField10.Enable = 'off';
            app.LabelNumericEditField10.Text = 'Delta C';
            app.deltac = uieditfield(app.Tab3, 'numeric');
            app.deltac.Enable = 'off';
            app.Button8 = uibutton(app.Tab3, 'push');
            app.Button8.ButtonPushedFcn = createCallbackFcn(app, @Button8ButtonPushed);
            app.Button8.Enable = 'off';
            app.Button8.Text = 'Add/Change';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Units = 'pixels';
            app.Tab4.Title = 'Fabrication Error';
            app.LabelNumericEditField11 = uilabel(app.Tab4);
            app.LabelNumericEditField11.Enable = 'off';
            app.LabelNumericEditField11.Text = 'Change m';
            app.deltam = uieditfield(app.Tab4, 'numeric');
            app.deltam.Enable = 'off';
            app.Button9 = uibutton(app.Tab4, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @Button9ButtonPushed);
            app.Button9.Enable = 'off';
            app.Button9.Text = 'Add/Change';
            app.LabelListBox4 = uilabel(app.Tab4);
            app.LabelListBox4.Text = 'Member List';
            app.memberfb = uilistbox(app.Tab4);
            app.memberfb.ValueChangedFcn = createCallbackFcn(app, @memberfbValueChanged);
            app.Button10 = uibutton(app.Tab4, 'push');
            app.Button10.ButtonPushedFcn = createCallbackFcn(app, @Button10ButtonPushed);
            app.Button10.Text = 'Create List';
            app.axe = uiaxes(app.UIFigure);
            title(app.axe, 'Structure');
            xlabel(app.axe, 'X Direction m');
            ylabel(app.axe, 'Y Direction m');
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5ButtonPushed);
            app.Button5.Text = 'Solve';
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'Solutions are in MATLAB Command Window';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'By sfktrkl';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = {'Green>Supports'; 'Blue>Nodes'; 'Black>Members'; 'Red>Forces'};
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = {'UNITS'; ''; 'kN'; 'm'; 'kN/m2'};
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Text = 'Discretization will shown in another Figure after solution.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TrussApp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end